classdef ICP_Base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BaseMenu                     matlab.ui.container.Menu
        OpenBaseMenu                 matlab.ui.container.Menu
        SaveBaseMenu                 matlab.ui.container.Menu
        SaveBaseasMenu               matlab.ui.container.Menu
        Saveh5Menu                   matlab.ui.container.Menu
        Saveash5Menu                 matlab.ui.container.Menu
        ProjectMenu                  matlab.ui.container.Menu
        ImportProjectsMenu           matlab.ui.container.Menu
        ExportProjectMenu            matlab.ui.container.Menu
        SessionMenu                  matlab.ui.container.Menu
        ImportSessionsMenu           matlab.ui.container.Menu
        ExportSessionMenu            matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        TableBuilderVisModuleButton  matlab.ui.control.Button
        DuplicateProjectButton       matlab.ui.control.Button
        DuplicateSessionButton       matlab.ui.control.Button
        SelectButton                 matlab.ui.control.Button
        WorkingDirectoryEditField    matlab.ui.control.EditField
        WorkingDirectoryLabel        matlab.ui.control.Label
        ResultsButton                matlab.ui.control.Button
        QuantificationButton         matlab.ui.control.Button
        DriftButton                  matlab.ui.control.Button
        ClassificationButton         matlab.ui.control.Button
        DeleteProjectButton          matlab.ui.control.Button
        AddProjectButton             matlab.ui.control.Button
        ProjectTable                 matlab.ui.control.Table
        IntervalButton               matlab.ui.control.Button
        DeleteSessionButton          matlab.ui.control.Button
        SessionTable                 matlab.ui.control.Table
        AddSessionButton             matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
    end

    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        base

        installpath
        diarypath
        workingdirectory

        default_plotstyle_path
        default_RM_path
        default_oxides_path
        default_VisModule_path


    end

    properties (Access = private)

        LastSelectedProjectRow

        ICP_Base_version ='1.3.0';
        
    end


    methods (Access = private)

        function update_base_GUI(app, project_id)

            update_project_table(app)
            update_session_table(app, project_id)

        end

        function update_project_table(app)

            % preallocate the columns
            project_name=cell(1,numel(app.base));
            %project_comment=cell(1,numel(app.base));

            % get the session names and other variables
            for i = 1:numel(app.base)
                % since the project name is now initialzed at
                % AddSessionButtonDown it will always have 'New Project'
                if isfield(app.base(i), 'project_name') && ~isempty(app.base(i).project_name)

                    project_name{i} = app.base(i).project_name;

                else
                    project_name{i}='New Project';
                end

            end

            % generate a table with the variables
            T=table((1:numel(project_name))', project_name');
            T.Properties.VariableNames={'#', 'Project Name'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.ProjectTable.Data = T;
            app.ProjectTable.ColumnName = T.Properties.VariableNames;

        end

        function update_session_table(app, project_id)

            % preallocate the columns
            session_name=cell(1,numel(app.base(project_id).project));
            session_comment=cell(1,numel(app.base(project_id).project));
            session_date = cell(1,numel(app.base(project_id).project));
            session_analyst = cell(1,numel(app.base(project_id).project));
            session_processor = cell(1,numel(app.base(project_id).project));

            % get the session names and other variables
            for i = 1:numel(app.base(project_id).project)

                session_name{i} = app.base(project_id).project(i).session.session_name;


                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'session_comment')...
                        && ~isempty(app.base(project_id).project(i).session.meta.session_comment)
                    session_comment{i} = char(app.base(project_id).project(i).session.meta.session_comment);
                else
                    session_comment{i}='';
                end

                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'date')...
                        && ~isempty(app.base(project_id).project(i).session.meta.date)
                    session_date{i} = char(app.base(project_id).project(i).session.meta.date);
                else
                    session_date{i}='';
                end

                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'analyst')...
                        && ~isempty(app.base(project_id).project(i).session.meta.analyst)
                    session_analyst{i} = char(app.base(project_id).project(i).session.meta.analyst);
                else
                    session_analyst{i}='';
                end

                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'processed_by')...
                        && ~isempty(app.base(project_id).project(i).session.meta.processed_by)
                    session_processor{i} = char(app.base(project_id).project(i).session.meta.processed_by);
                else
                    session_processor{i}='';
                end

            end

            % generate a table with the variables
            T=table((1:numel(session_name))', session_name', session_date', session_comment', session_analyst', session_processor');
            T.Properties.VariableNames={'#', 'Session Name', 'Date YYYYMMDD', 'Comment', 'Analyst', 'Processed by'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.SessionTable.Data = T;
            app.SessionTable.ColumnName = T.Properties.VariableNames;

        end

        function update_progress_buttons(app, project_id, session_id)

            status = app.base(project_id).project(session_id).session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            app.ImportButton.Visible = "on";
            app.IntervalButton.Visible = "on";
            app.ClassificationButton.Visible = "on";
            app.DriftButton.Visible = "on";
            app.QuantificationButton.Visible = "on";
            app.ResultsButton.Visible = "on";

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end
        end


        %%%%%%%%%%%%%%% Startup function stuff %%%%%%%%%%%%%%%%%%%%

        function check_for_update_fun(app,state)
            if not(exist("state","var"))
                state=false;
            end
            try
                % Define the owner and repo for your GitHub repository

                owner = 'GeoSebastianStumpf';
                repo = 'ICP-Base';
                url = sprintf('https://api.github.com/repos/%s/%s/releases', owner, repo);

                % Make the GET request to the GitHub API without authentication
                releaseData = webread(url);

                % If you want to extract specific fields, such as the tag name:
                if ~isempty(releaseData)
                    latestReleaseTag = releaseData(1).tag_name; % The first release in the array (latest)
                    disp(['Latest release tag: ' latestReleaseTag]);

                    if not(strcmp(latestReleaseTag, app.ICP_Base_version))

                        message = sprintf(['A new version of ICP-Base is available: <b>%s</b><br><br>'...
                            'You can download it from the following link: <a href="%s">https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest</a>'], ...
                            latestReleaseTag, 'https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest');

                        uialert(app.UIFigure,message,'New Version Available','Interpreter','html','Icon','info');
                    elseif state==true
                        message = sprintf('You have the latest version of ICP-Base installed: <b>%s</b><br><br>' , ...
                            latestReleaseTag);
                        uialert(app.UIFigure,message,'Latest Version Installed','Interpreter','html','Icon','success');

                    end

                else
                    disp('No releases found.');
                end
            catch ME
                if contains(ME.message, 'timeout') || contains(ME.message, 'Unable to resolve host') || contains(ME.message, 'Could not access server. https://api.github.com/repos/GeoSebastianStumpf/ICP-Base/releases.')
                    disp('New Version Check Failed - Unable to connect to GitHub. Please check your internet connection and try again.');
                elseif contains(ME.message, '404')
                    disp('New Version Check Failed - Repository not found. Please check the repository details.');
                else
                    DisplayError(ME,'Startup New Version Check Failed')
                end

            end
        end

        function startup_time_and_ver_fun(app)

            disp('')

            disp('_______________________________________')

            disp('=======================================')

            disp('              ICP-Base')

            disp('=======================================')

            disp(['Version: ' app.ICP_Base_version])

                disp(['Launch Time: ' datestr(now,'dd-mmm-yyyy HH:MM:SS')])

            disp('---------------------------------------')

        end


        %%%%%%%%%%%%%%% Backwards compatibility %%%%%%%%%%%%%%%

        function migrate_base(app)
            for i = 1:numel(app.base)
                app.base(i).meta = migrateStruct(app, app.base(i).meta);
                for j = 1:numel(app.base(i).project)

                    sess = app.base(i).project(j).session;
                    sess = migrateStruct(app, sess);

                    if isfield(sess, 'spot') && isfield(sess.spot, 'comment')
                        c = sess.spot.comment;
                        if iscell(c)
                            result = strings(size(c));
                            for k = 1:numel(c)
                                v = c{k};
                                if ischar(v) || isstring(v)
                                    result(k) = string(v);
                                end
                            end
                            c = result;
                        elseif ischar(c)
                            c = string(c);
                        end
                        sess.meta.comment = c;
                        sess.spot = rmfield(sess.spot, 'comment');
                    end

                    if isfield(sess, 'oxides') && isfield(sess.oxides, 'conversion_factors')
                        sess.oxides = migrateOxides(app, sess.oxides);
                    end

                    app.base(i).project(j).session = sess;
                end
            end
        end

        % =========================================================
        function s = migrateStruct(app, s)
            if ~isscalar(s)
                for i = 1:numel(s)
                    s(i) = migrateStruct(app, s(i));
                end
                return;
            end

            %These stay cell arrays as they can hold mixed data
            skipFields = {'internal_standard_mass_fraction', ...
                'internal_standard_mass_fraction_inclusion', ...
                'second_internal_standard_mass_fraction_inclusion'};

            fields = fieldnames(s);
            for i = 1:numel(fields)
                fname = fields{i};
                if ismember(fname, skipFields)
                    continue;
                end
                s.(fname) = migrateValue(app, s.(fname));
            end

            % Group 1 — direct cell → double array
            g1 = {'matrix_1_starts','matrix_1_ends','matrix_2_starts','matrix_2_ends', ...
                'matrix_3_starts','matrix_3_ends','background_starts','background_ends', ...
                'inclusion_1_starts','inclusion_1_ends'};
            for f = 1:numel(g1)
                fn = g1{f};
                if isfield(s, fn) && iscell(s.(fn))
                    c = s.(fn);
                    for k = 1:numel(c)
                        if isempty(c{k}), c{k} = NaN; end
                    end
                    try
                        s.(fn) = cell2mat(c);
                    catch
                    end
                end
            end

            % Group 2 — wrapper cell, inner cell → string array
            g2 = {'internal_standard','internal_standard_inclusion','second_internal_standard_inclusion'};
            for f = 1:numel(g2)
                fn = g2{f};
                if isfield(s, fn) && iscell(s.(fn))
                    outer = s.(fn);
                    for k = 1:numel(outer)
                        if iscell(outer{k})
                            inner = outer{k};
                            result = strings(size(inner));
                            for j = 1:numel(inner)
                                if ~isempty(inner{j})
                                    result(j) = string(inner{j});
                                end
                            end
                            outer{k} = result;
                        end
                    end
                    s.(fn) = outer;
                end
            end

            % Group 3 — wrapper cell, inner cell → double array
            g3 = {'internal_standard_oxide_wt','internal_standard_oxide_wt_inclusion', ...
                'Fe_ratio_for_total_oxide','Fe_ratio_for_total_oxide_inclusion'};
            for f = 1:numel(g3)
                fn = g3{f};
                if isfield(s, fn) && iscell(s.(fn))
                    outer = s.(fn);
                    for k = 1:numel(outer)
                        if iscell(outer{k})
                            inner = outer{k};
                            result = NaN(size(inner));
                            for j = 1:numel(inner)
                                if ~isempty(inner{j})
                                    result(j) = double(inner{j});
                                end
                            end
                            outer{k} = result;
                        end
                    end
                    s.(fn) = outer;
                end
            end
        end

        % =========================================================
        function val = migrateValue(app, val)
            if isstruct(val)
                val = migrateStruct(app, val);
            elseif iscell(val)
                val = migrateCell(app, val);
            end
            % numeric, string, char, table, containers.Map → return as-is
        end

        % =========================================================
        function c = migrateCell(app, c)
            nonEmpty = {};
            for i = 1:numel(c)
                if ~isempty(c{i})
                    nonEmpty{end+1} = c{i};
                end
            end

            if isempty(nonEmpty)
                return;
            end

            firstVal = nonEmpty{1};

            % Mixed numeric + string → mass_fraction type, leave as-is
            hasNum = any(cellfun(@(x) isnumeric(x) && ~isempty(x), c(:)));
            hasStr = any(cellfun(@(x) (ischar(x) || isstring(x)) && ~isempty(x), c(:)));
            if hasNum && hasStr
                return;
            end

            if ischar(firstVal)
                result = strings(size(c));
                for i = 1:numel(c)
                    if isempty(c{i})
                        result(i) = "";
                    else
                        result(i) = string(c{i});
                    end
                end
                c = result;
            elseif isnumeric(firstVal) && isscalar(firstVal)
                cFilled = c;
                for ii = 1:numel(cFilled)
                    if isempty(cFilled{ii})
                        cFilled{ii} = NaN;
                    end
                end
                try
                    c = cell2mat(cFilled);
                catch
                end
            elseif iscell(firstVal)
                % Cell of cells → recurse into each
                for i = 1:numel(c)
                    if ~isempty(c{i})
                        c{i} = migrateCell(app, c{i});
                    end
                end
            end
            % tables, string arrays, numeric arrays → leave as-is
        end

        % =========================================================
        function oxides = migrateOxides(app, oxides)
            en  = oxides.element_names;   % already string array after migrateStruct
            map = oxides.conversion_factors;

            cf = zeros(numel(en), 1);
            for k = 1:numel(en)
                key = char(en(k));
                if isKey(map, key)
                    cf(k) = map(key);
                end
            end
            oxides.conversion_factor_values = cf;
            oxides = rmfield(oxides, 'conversion_factors');

            if ~isfield(oxides, 'considered_major')
                majors = ["Si", "Al", "Fe", "Mg", "Na", "K", "Ca", "Mn", "Ti"];
                considered = strings(numel(en), 1);
                for k = 1:numel(en)
                    if ismember(en(k), majors)
                        considered(k) = "Yes";
                    else
                        considered(k) = "No";
                    end
                end
                oxides.considered_major = considered;
            end
        end


        %%%%%%%%%%%%%%% full .mat base to h5 export %%%%%%%%%%%%%%%

        function base2h5(app, base, filepath)

            [~, name, ext] = fileparts(filepath);
            if isempty(name) || isempty(ext)
                uialert(app.UIFigure, 'Invalid file path — select a file, not a folder.', 'Error');
                return;
            end

            if exist(filepath, 'file') == 2
                delete(filepath);
            end

            for i = 1:numel(base)
                projectpath = ['/' sprintf('%d_project', i)];

                writeValue(app, base(i).project_name, filepath, [projectpath '/project_name']);
                writeStructToH5(app, base(i).meta, filepath, [projectpath '/meta']);

                for j = 1:numel(base(i).project)
                    sesh  = base(i).project(j).session;
                    spath = [projectpath '/' sprintf('%d_session', j)];
                    writeStructToH5(app, sesh, filepath, spath);
                end
            end

        end

        % =========================================================
        function writeStructToH5(app, s, filepath, grouppath)
            if ~isscalar(s)
                for i = 1:numel(s)
                    ipath = sprintf('%s/item_%d', grouppath, i);
                    writeStructToH5(app, s(i), filepath, ipath);
                end
                csizepath = [grouppath '/__struct_size__'];
                h5create(filepath, csizepath, [1 2]);
                h5write(filepath,  csizepath, double(size(s)));
                if ~isempty(grouppath)
                    h5writeatt(filepath, grouppath, 'original_type', 'struct_array');
                end
                return;
            end
            fields = fieldnames(s);
            for i = 1:numel(fields)
                fname = fields{i};
                val   = s.(fname);
                fpath = [grouppath '/' fname];
                writeValue(app, val, filepath, fpath);
            end
        end

        % =========================================================
        function writeValue(app, val, filepath, path)
            if isstruct(val)
                writeStructToH5(app, val, filepath, path);
            elseif istable(val)
                writeTable(app, val, filepath, path);
            elseif iscell(val)
                writeCellArray(app, val, filepath, path);
            elseif isnumeric(val) || islogical(val)
                writeNumeric(app, val, filepath, path);
            elseif isstring(val) || ischar(val)
                writeString(app, val, filepath, path);
            else
                warning('base2h5: skipping unsupported type at %s (%s)', path, class(val));
            end
        end

        % =========================================================
        function writeNumeric(~, val, filepath, path)
            if isempty(val)
                h5create(filepath, path, [1 1]);
                h5write(filepath,  path, 0);
                h5writeatt(filepath, path, 'is_empty', int32(1));
            else
                data = double(val);
                h5create(filepath, path, size(data));
                h5write(filepath,  path, data);
            end
            h5writeatt(filepath, path, 'original_type', 'double');
        end

        % =========================================================
        function writeString(~, val, filepath, path)
            if ischar(val)
                if isempty(val)
                    h5create(filepath, path, [1 1], 'Datatype', 'string');
                    h5write(filepath,  path, {''});
                    h5writeatt(filepath, path, 'is_empty', int32(1));
                else
                    h5create(filepath, path, [1 1], 'Datatype', 'string');
                    h5write(filepath,  path, {val});
                end
            else  % string array
                if isempty(val)
                    h5create(filepath, path, [1 1], 'Datatype', 'string');
                    h5write(filepath,  path, {''});
                    h5writeatt(filepath, path, 'is_empty', int32(1));
                else
                    cs = cellstr(val);
                    h5create(filepath, path, size(cs), 'Datatype', 'string');
                    h5write(filepath,  path, cs);
                end
            end
            h5writeatt(filepath, path, 'original_type', 'string');
        end

        % =========================================================
        function writeTable(app, t, filepath, path)
            colnames = t.Properties.VariableNames;

            if isempty(colnames)
                h5create(filepath, [path '/colnames'], [1 1], 'Datatype', 'string');
                h5write(filepath,  [path '/colnames'], {''});
            else
                h5create(filepath, [path '/colnames'], [1 numel(colnames)], 'Datatype', 'string');
                h5write(filepath,  [path '/colnames'], colnames);
            end

            if isempty(t)
                h5create(filepath, [path '/data'], [1 1]);
                h5write(filepath,  [path '/data'], 0);
                h5writeatt(filepath, path, 'is_empty',      int32(1));
                h5writeatt(filepath, path, 'original_type', 'table');
                h5writeatt(filepath, path, 'table_size',    double([0 width(t)]));
                return;
            end

            allNum = true;
            for j = 1:width(t)
                col = t{:, j};
                if ~isnumeric(col) && ~islogical(col)
                    allNum = false;
                    break;
                end
            end

            if allNum
                data = double(table2array(t));
                h5create(filepath, [path '/data'], size(data));
                h5write(filepath,  [path '/data'], data);
            else
                for j = 1:width(t)
                    cn  = colnames{j};
                    col = t.(cn);
                    writeValue(app, col, filepath, [path '/col_' cn]);
                end
            end

            h5writeatt(filepath, path, 'original_type', 'table');
            h5writeatt(filepath, path, 'table_size', double([height(t) width(t)]));
        end

        % =========================================================
        function writeCellArray(app, c, filepath, path)
            firstVal = [];
            for i = 1:numel(c)
                if ~isempty(c{i})
                    firstVal = c{i};
                    break;
                end
            end

            if isempty(firstVal)
                h5create(filepath, [path '/__cell_empty__'], [1 1]);
                h5write(filepath,  [path '/__cell_empty__'], 0);
                h5writeatt(filepath, path, 'original_type', 'cell_empty');
                h5writeatt(filepath, path, 'cell_size', double(size(c)));
                return;
            end

            if istable(firstVal)
                cellType = 'cell_of_tables';
            elseif iscell(firstVal)
                cellType = 'cell_of_cells';
            else
                cellType = 'cell_of_arrays';
            end

            for i = 1:numel(c)
                ipath = sprintf('%s/item_%d', path, i);
                if isempty(c{i})
                    h5create(filepath, [ipath '/__empty__'], [1 1]);
                    h5write(filepath,  [ipath '/__empty__'], 0);
                    h5writeatt(filepath, ipath, 'is_empty', int32(1));
                else
                    switch cellType
                        case 'cell_of_tables'
                            writeTable(app, c{i}, filepath, ipath);
                        case 'cell_of_cells'
                            writeMixedCell(app, c{i}, filepath, ipath);
                        case 'cell_of_arrays'
                            writeValue(app, c{i}, filepath, ipath);
                    end
                end
            end

            csizepath = [path '/__cell_size__'];
            h5create(filepath, csizepath, [1 2]);
            h5write(filepath,  csizepath, double(size(c)));
            h5writeatt(filepath, path, 'original_type', cellType);
            h5writeatt(filepath, path, 'cell_size', double(size(c)));
        end

        % =========================================================
        function writeMixedCell(~, innerCell, filepath, path)
            strVals  = cell(size(innerCell));
            isDouble = false(size(innerCell));

            for j = 1:numel(innerCell)
                v = innerCell{j};
                if isempty(v)
                    strVals{j}  = '';
                    isDouble(j) = false;
                elseif isnumeric(v)
                    isDouble(j) = true;
                    strVals{j}  = num2str(v, '%.15g');
                else
                    strVals{j} = char(v);
                end
            end

            h5create(filepath, [path '/values'],    size(strVals), 'Datatype', 'string');
            h5write(filepath,  [path '/values'],    strVals);
            h5create(filepath, [path '/is_double'], size(isDouble));
            h5write(filepath,  [path '/is_double'], double(isDouble));
            h5writeatt(filepath, path, 'original_type', 'mixed_cell');
            h5writeatt(filepath, path, 'cell_size', double(size(innerCell)));
        end

        %%%%%%%%%%%%%%% full base h5 import %%%%%%%%%%%%%%%%%%%%

        function connect_h5(app, filepath)
            info = h5info(filepath);
            if isempty(info.Groups)
                return;
            end

            pGroups = h5sortGroups(app, info.Groups);

            for i = 1:numel(pGroups)
                ppath = pGroups(i).Name;

                % project_name
                app.base(i).project_name = h5readStr(app, filepath, [ppath '/project_name']);
                app.base(i).meta.basepath = filepath;

                % session groups only (numeric-prefixed, excludes 'meta')
                sGroups = h5sessionGroups(app, pGroups(i).Groups);

                for j = 1:numel(sGroups)
                    spath = sGroups(j).Name;

                    % session_name
                    app.base(i).project(j).session.session_name = h5readStr(app, filepath, [spath '/session_name']);

                    for fn = ["session_comment", "date", "analyst", "processed_by"]
                        try
                            raw = h5read(filepath, [spath '/meta/' char(fn)]);
                            if iscell(raw), raw = raw{1}; end
                            app.base(i).project(j).session.meta.(char(fn)) = string(raw);
                        catch
                        end
                    end

                    % config.module_status
                    msPath = [spath '/config/module_status'];
                    try
                        msInfo = h5info(filepath, msPath);
                        for k = 1:numel(msInfo.Datasets)
                            dsname = msInfo.Datasets(k).Name;
                            app.base(i).project(j).session.config.module_status.(dsname) = ...
                                h5read(filepath, [msPath '/' dsname]);
                        end
                    catch
                    end
                end
            end
        end

        % =========================================================
        % Read a scalar string from H5, return "" on failure/empty
        function val = h5readStr(~, filepath, path)
            try
                raw = h5read(filepath, path);
                if iscell(raw), raw = raw{1}; end
                val = string(raw);
            catch
                val = "";
            end
        end

        % =========================================================
        % Sort H5 group struct array by numeric prefix (1_Name, 2_Name, ...)
        function sorted = h5sortGroups(~, groups)
            if isempty(groups)
                sorted = groups;
                return;
            end
            idx = zeros(numel(groups), 1);
            for k = 1:numel(groups)
                parts = strsplit(groups(k).Name, '/');
                tok = regexp(parts{end}, '^(\d+)', 'tokens', 'once');
                if ~isempty(tok)
                    idx(k) = str2double(tok{1});
                end
            end
            [~, order] = sort(idx);
            sorted = groups(order);
        end

        % =========================================================
        % Filter subgroups to session groups only (numeric-prefixed)
        function sGroups = h5sessionGroups(app, groups)
            keep = false(numel(groups), 1);
            for k = 1:numel(groups)
                parts = strsplit(groups(k).Name, '/');
                keep(k) = ~isempty(regexp(parts{end}, '^\d+_', 'once'));
            end
            sGroups = h5sortGroups(app, groups(keep));
        end

        %%%%%%%%%%%%%%% h5 GUI shit %%%%%%%%%%%%%%%%%%%%

        % =========================================================
        % Write or overwrite a scalar string dataset in H5
        function h5writeStrVal(~, filepath, path, val)
            try
                h5create(filepath, path, [1 1], 'Datatype', 'string');
            catch
            end
            h5write(filepath, path, {char(val)});
            h5writeatt(filepath, path, 'original_type', 'string');
            h5writeatt(filepath, path, 'is_empty', int32(val == ""));
        end


    end


    methods (Access = public)

        % this function needs to sort everything back in the base
        function [project_id, session_id] = save_to_base(app, project_id, session, session_id, project_name, settings)
            try
                %% sort in the session using the ids
                %% if the id is a number higher than 1, it will just go to that place

                % if I open the import module from the base, this is not needed
                % because the project_id is sent to the Import_Module
                % if the user decides to input 0 for all the project_ids
                if isequal(project_id, 0) || isempty(project_id)
                    project_id = numel(app.base) + 1;
                end

                % make an empty struct if a new project is created throught the
                % API
                if project_id > numel(app.base)
                    app.base(project_id).project = struct();
                end

                % these are for sorting the sessions back into the right projects
                if numel(app.base) == 0 % completely new base initialzed by API
                    session_id = 1;
                elseif isempty(fieldnames(app.base(project_id).project)) || ~isfield(app.base(project_id).project, 'session') || isempty(app.base(project_id).project)
                    session_id = 1;
                elseif isequal(session_id, 0) || isempty(session_id)
                    session_id = numel(app.base(project_id).project) + 1;
                end

                % save the session at the right place in the base
                app.base(project_id).project(session_id).session = session;

                %% session and project names

                app.base(project_id).project_name = project_name;

                % check if there is a session_name already in the session
                % struct, if not name it New Session
                if ~isfield(app.base(project_id).project(session_id).session, 'session_name') || isempty(app.base(project_id).project(session_id).session.session_name)
                    app.base(project_id).project(session_id).session.session_name = 'New Session';
                end

                %% GUI update
                if isfield(settings, 'ICP_Base_GUI_update_state') && settings.ICP_Base_GUI_update_state == true
                    update_base_GUI(app, project_id)

                    update_progress_buttons(app, project_id, session_id)
                end
            catch ME
                DisplayError(ME,'Error - save_to_base')
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');

            if isdeployed %standalone mode.
                try
                    if ispc

                        [~, result] = system('path');
                        app.installpath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                    elseif ismac

                        path=ctfroot;
                        disp('ctfroot:')
                        disp(path)

                        path_split=split(path,{'ICP_Base.app'});
                        disp('path_split')
                        disp(path_split)

                        app.installpath=path_split{1};
                        disp('Installation Directory 0:')
                        disp(app.installpath)
                        disp('')

                        if not(exist(fullfile(app.installpath,'Total_Oxide_Calculations','Oxides.csv'),'file')) % this is shit and needs to be changed
                            answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');
                        end

                    elseif isunix

                        try
                            app.installpath=pwd;
                        catch
                            answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');

                        end

                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    user_path=userpath;
                    disp('Userpath -2:')
                    disp(user_path)

                    user_path=replace(user_path,'/MATLAB','');
                    user_path=replace(user_path,'\MATLAB','');
                    disp('Userpath -1:')
                    disp(user_path)
                    user_path=fullfile(user_path,'ICP-Base');

                    disp('Userpath 0:')
                    disp(user_path)

                    if ~exist(user_path,'dir')
                        try
                            disp('Userpath does not exist')
                            [status, msg, ~] = mkdir(user_path);
                            if status==0
                                answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');

                                disp('Userpath folder could not be created:')
                                disp(msg)
                                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                                user_path=uigetdir(pwd,'select working directory with read/write permission');
                                delete(f)
                                %   disp('Userpath folder could not be created')
                            else
                                disp('Userpath folder created:')
                                disp(user_path)
                            end

                        catch ME
                            DisplayError(ME,'Startup Error mkdir')

                            if ~exist(user_path,'dir')
                                answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                                % uialert(app.UIFigure,'To avoide this message create a folder called ICP-Base in your Documents folder',' Error');
                                user_path=uigetdir(pwd,'select working directory with read/write permission');
                            end
                        end
                    end

                    app.diarypath = fullfile(user_path, 'ICP-Base_log.txt');
                    diary(app.diarypath);

                    startup_time_and_ver_fun(app)

                    disp('Logfile Directory:')
                    disp(user_path)

                    disp('Installation Directory:')
                    disp(app.installpath)
                    disp('')

                    app.WorkingDirectoryEditField.Value=user_path;
                    app.WorkingDirectoryEditField.Tooltip=user_path;
                   
                catch ME
                    DisplayError(ME,'Startup Error 1')
                end

            else % undeployed

                % Set install path to the location of the .mlapp file
                app.installpath = fileparts(which('ICP_Base.mlapp'));

                % --- Define user_path to be Documents/ICP-Base ---
                user_path = userpath;
                user_path = replace(user_path,'/MATLAB','');
                user_path = replace(user_path,'\MATLAB','');
                user_path = fullfile(user_path,'ICP-Base');

                % Create the directory if it doesn't exist
                if ~exist(user_path, 'dir')
                    try
                        mkdir(user_path);
                        disp(['Created working directory for logging: ' user_path]);
                    catch
                        disp('Could not create log directory. Log will be written to current folder.');
                        user_path = pwd; % Fallback to current directory
                    end
                end

                app.diarypath = fullfile(user_path, 'ICP-Base_log.txt');
                diary(app.diarypath);

                startup_time_and_ver_fun(app)

                disp('Logfile Directory:')
                disp(user_path)

                disp('Installation Directory:')
                disp(app.installpath)
                disp('')

                % Set the UI to show the *current* MATLAB directory as the working folder
                app.WorkingDirectoryEditField.Value = pwd;
                app.WorkingDirectoryEditField.Tooltip = app.WorkingDirectoryEditField.Value;

            end


            % -------------------------------------------------------------
            % PLOT STYLE INITIALIZATION
            % -------------------------------------------------------------

            source_styles = fullfile(app.installpath, 'Plot_Styles');
            app.default_plotstyle_path = fullfile(user_path, 'Plot_Styles');

            % Logic: Check if user folder exists. If NOT, copy from installation.
            if ~exist(app.default_plotstyle_path, 'dir')
                if exist(source_styles, 'dir')
                    try
                        disp('Initialized Plot_Styles folder in:');
                        disp( app.default_plotstyle_path)
                        [status, msg] = copyfile(source_styles, app.default_plotstyle_path);

                        if ~status
                            disp(['Error copying Plot_Styles: ' msg]);
                            % Fallback to source (read-only) if copy fails
                            app.default_plotstyle_path = source_styles;
                        end
                    catch ME
                        DisplayError(ME,'Error intitializing Plot_Styles folder')
                        app.default_plotstyle_path = source_styles;
                    end
                end
            else
                disp('Plot Styles Directory:');
                disp( app.default_plotstyle_path)
            end
            % -------------------------------------------------------------


            % -------------------------------------------------------------
            % RM FILES INITIALIZATION
            % -------------------------------------------------------------

            source_rms = fullfile(app.installpath, 'RM_Files');
            app.default_RM_path = fullfile(user_path, 'RM_Files');

            % Logic: Check if user folder exists. If NOT, copy from installation.
            if ~exist(app.default_RM_path, 'dir')
                if exist(source_rms, 'dir')
                    try
                        disp('Initialized RM_Files folder in:');
                        disp( app.default_RM_path)
                        [status, msg] = copyfile(source_rms, app.default_RM_path);

                        if ~status
                            disp(['Error copying RM_Files folder: ' msg]);
                            % Fallback to source (read-only) if copy fails
                            app.default_RM_path = source_rms;
                        end
                    catch ME
                        DisplayError(ME,'Error intitializing RM_Files folder')
                        app.default_RM_path = source_rms;
                    end
                end
            else
                disp('RM Files Directory:');
                disp( app.default_RM_path)
            end
            % -------------------------------------------------------------


            % -------------------------------------------------------------
            % Total_Oxide_Calculations INITIALIZATION
            % -------------------------------------------------------------

            source_oxide = fullfile(app.installpath, 'Total_Oxide_Calculations');
            app.default_oxides_path = fullfile(user_path, 'Total_Oxide_Calculations');

            % Logic: Check if user folder exists. If NOT, copy from installation.
            if ~exist(app.default_oxides_path, 'dir')
                if exist(source_oxide, 'dir')
                    try
                        disp('Initialized Total_Oxide_Calculations folder in:');
                        disp( app.default_oxides_path)
                        [status, msg] = copyfile(source_oxide, app.default_oxides_path);

                        if ~status
                            disp(['Error copying Total_Oxide_Calculations folder: ' msg]);
                            % Fallback to source (read-only) if copy fails
                            app.default_oxides_path = source_oxide;
                        end
                    catch ME
                        DisplayError(ME,'Error intitializing Total_Oxide_Calculations folder')
                        app.default_oxides_path = source_oxide;
                    end
                end
            else
                disp('Total Oxide Calculations Directory:');
                disp( app.default_oxides_path)
            end
            % -------------------------------------------------------------


            % -------------------------------------------------------------
            % MinPlotX's VisModule INITIALIZATION
            % -------------------------------------------------------------

            % 1. Define Source FOLDER (Where the original files live)
            source_folder = fullfile(app.installpath, 'Modules', 'VisModule');

            % 2. Define Destination FOLDER (User Documents)
            app.default_VisModule_path = fullfile(user_path, 'VisModule_Resources');

            % Files to copy
            file1 = 'XMap_SpiderNormalizations.txt';
            file2 = 'XMap_ColorMaps.txt';

            % Logic: Check if user FOLDER exists.
            if ~exist(app.default_VisModule_path, 'dir')
                try
                    % Create the new folder
                    [status_mkdir, msg_mkdir] = mkdir(app.default_VisModule_path);

                    if status_mkdir
                        disp('Created VisModule Resources folder in:');
                        disp(app.default_VisModule_path);

                        % Copy File 1
                        if exist(fullfile(source_folder, file1), 'file')
                            copyfile(fullfile(source_folder, file1), app.default_VisModule_path);
                        end

                        % Copy File 2
                        if exist(fullfile(source_folder, file2), 'file')
                            copyfile(fullfile(source_folder, file2), app.default_VisModule_path);
                        end

                    else
                        disp(['Error creating directory: ' msg_mkdir]);
                        app.default_VisModule_path = source_folder; % Fallback
                    end

                catch ME
                    DisplayError(ME, 'Error initializing VisModule Resources');
                    app.default_VisModule_path = source_folder; % Fallback
                end
            else
                disp('VisModule Resources Directory:');
                disp(app.default_VisModule_path);
            end
            % -------------------------------------------------------------


            % check for new version
            check_for_update_fun(app)

            disp('---------------------------------------')
            disp('=======================================')
            disp('                                       ')
        end

        % Button pushed function: AddSessionButton
        function AddSessionButtonPushed(app, event)

            %% get the project_id as the selection of the ProjectTable

            project_id = app.LastSelectedProjectRow;

            if isempty(project_id)
                message = "Select a Project first";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            session_id = 0;
            Import_Module(app, app.base(project_id).project_name, project_id, session_id)


        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ImportButton, IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)
            try
                % check if selection is not empty
                if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                    % session_id is the selection
                    session_id = app.SessionTable.Selection(1);
                    % the project_id is the last selected project in the
                    % project table
                    project_id = app.LastSelectedProjectRow;

                    switch event.Source.Parent.Parent.Parent.CallbackObject.Text
                        case 'Show Session in Import Module'
                            Import_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Interval Module'
                            Interval_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Classification Module'
                            Classification_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Drift Module'
                            Drift_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Quantification Module'
                            Quantification_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Results Module'
                            Results_Module(app, app.base(project_id).project_name, project_id, session_id)
                    end

                end

            catch ME
                DisplayError(ME,'Error when proceeding from the module buttons')
            end


        end

        % Cell edit callback: SessionTable
        function SessionTableCellEdit(app, event)

            sel_row_session = app.SessionTable.Selection(1);
            sel_col_session = app.SessionTable.Selection(2);
            pid     = app.LastSelectedProjectRow;
            colname = app.SessionTable.ColumnName{sel_col_session};

            try
                basepath = app.base(1).meta.basepath;
                [~,~,bext] = fileparts(basepath);
                if strcmp(bext, '.h5') && isfile(basepath)
                    spath = sprintf('/%d_project/%d_session', pid, sel_row_session);
                    switch colname
                        case 'Session Name'
                            h5writeStrVal(app, basepath, [spath '/session_name'], string(event.NewData));
                        case 'Comment'
                            h5writeStrVal(app, basepath, [spath '/meta/session_comment'], string(event.NewData));
                        case 'Date YYYYMMDD'
                            h5writeStrVal(app, basepath, [spath '/meta/date'], string(event.NewData));
                        case 'Analyst'
                            h5writeStrVal(app, basepath, [spath '/meta/analyst'], string(event.NewData));
                        case 'Processed by'
                            h5writeStrVal(app, basepath, [spath '/meta/processed_by'], string(event.NewData));
                    end
                end

                switch colname
                    case 'Session Name'
                        app.base(pid).project(sel_row_session).session.session_name = event.NewData;
                    case 'Comment'
                        app.base(pid).project(sel_row_session).session.meta.session_comment = event.NewData;
                    case 'Date YYYYMMDD'
                        app.base(pid).project(sel_row_session).session.meta.date = event.NewData;
                    case 'Analyst'
                        app.base(pid).project(sel_row_session).session.meta.analyst = event.NewData;
                    case 'Processed by'
                        app.base(pid).project(sel_row_session).session.meta.processed_by = event.NewData;
                end

            catch ME
                DisplayError(ME, 'Writing session metadata to h5 failed')
            end
        end

        % Button pushed function: AddProjectButton
        function AddProjectButtonPushed(app, event)
            %% initializing empty projects in the base
            if not(isfield(app.base, 'project'))

                app.base(1).project = struct();
                app.base(1).project_name = 'New Project';

            else % the entry_id

                entry_id = numel(app.base) + 1;

                app.base(entry_id).project = struct();
                app.base(entry_id).project_name = 'New Project';

            end

            update_project_table(app)
        end

        % Callback function: ProjectTable
        function ProjectTableSelectionChanged(app, event)
            %% updates the session table when a project is selected
            if ~isempty(app.ProjectTable.Selection)
                if not(isempty(app.ProjectTable.Selection(1)))
                    selection = app.ProjectTable.Selection(1);
                else
                    return
                end

                if not(isempty(fieldnames(app.base(selection).project))) && isfield(app.base(selection).project, 'session')
                    update_session_table(app, selection)
                else
                    app.SessionTable.Data = {};
                end
            else
                return
            end

            %% failsafe if a project is clicked on but then it is clicked
            % somewhere in the ProjectTable where there is no project, the
            % selection gets set to [] and the conncection to the project
            % is lost
            % so the last clicked on row is always saved to always get back
            % to the project
            % Check if there is a selection in the ProjectTable
            if isempty(app.ProjectTable.Selection)
                % Use the last stored selection if the current selection is empty
                app.LastSelectedProjectRow = app.LastSelectedProjectRow;
            else
                % Update the last stored selection with the current selection
                app.LastSelectedProjectRow = app.ProjectTable.Selection(1);
            end

            % if Tableselection changed make all buttons just grey

            app.ImportButton.Visible = "off";
            app.IntervalButton.Visible = "off";
            app.ClassificationButton.Visible = "off";
            app.DriftButton.Visible = "off";
            app.QuantificationButton.Visible = "off";
            app.ResultsButton.Visible = "off";

            app.SessionTable.Selection = [];
        end

        % Menu selected function: SaveBaseMenu
        function SaveBaseMenuSelected(app, event)

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Base...', ...
                'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');


            if isfield(app.base, 'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;
                try

                    save(app.base(1).meta.basepath, 'data2save', '-v7.3', '-nocompression'); %

                catch

                    message = "Saving the base failed. Did you change the file location of the base (.mat) file? Use the 'Save Base as' option.";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");

                end

                app.UIFigure.Name = app.base(1).meta.basepath;

            else
                SaveBaseasMenuSelected(app)
            end

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath')

                app.UIFigure.Name = app.base(1).meta.basepath;

            end

            delete(progress)
        end

        % Menu selected function: OpenBaseMenu
        function OpenBaseMenuSelected(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file, path] = uigetfile({'*.mat;*.h5', 'Base Files (*.mat, *.h5)'}, "MultiSelect", "off", 'Open Base');
            delete(f);
            figure(app.UIFigure);

            if isequal(file, 0)
                return
            end

            [~, fname, ext] = fileparts(file);

            if strcmp(ext, '.mat')

                h5path = fullfile(path, [fname '.h5']);

                answer = uiconfirm(app.UIFigure, ...
                    sprintf('This base will be converted to HDF5 format and saved as:\n\n%s\n\nThe original .mat file will no longer be used. All work will be done on the .h5 file.', h5path), ...
                    'Convert to HDF5', ...
                    'Options', {'OK', 'Cancel'}, ...
                    'DefaultOption', 'OK', ...
                    'CancelOption', 'Cancel');

                if strcmp(answer, 'Cancel')
                    return
                end

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Loading, migrating and saving to HDF5...', ...
                    'Message', 'Depending on the size of the base, this can take a few minutes.', ...
                    'Cancelable', 'off', 'Indeterminate', 'on');

                % Import original .mat file
                data2import = load(fullfile(path, file));
                app.base = data2import.data2save.base;

                app.base(1).meta.basepath = h5path;
                % update the base to string and double arrays
                migrate_base(app);
                % write to h5
                base2h5(app, app.base, h5path);
                % empty the base
                app.base = struct([]);
                % fill it with the data from h5
                connect_h5(app, h5path);

                update_project_table(app)

                delete(progress)

            elseif strcmp(ext, '.h5')

                % TODO: connect to H5 file and load session list
                connect_h5(app, fullfile(path, file));

                update_project_table(app)

            end
        end

        % Cell edit callback: ProjectTable
        function ProjectTableCellEdit(app, event)
            sel_row_project = app.ProjectTable.Selection(1);
            sel_col_project = app.ProjectTable.Selection(2);

            if strcmp(app.ProjectTable.ColumnName{sel_col_project}, 'Project Name')
                try
                    basepath = app.base(1).meta.basepath;
                    [~,~,bext] = fileparts(basepath);
                    if strcmp(bext, '.h5') && isfile(basepath)
                        ppath = sprintf('/%d_project', sel_row_project);
                        h5writeStrVal(app, basepath, [ppath '/project_name'], string(event.NewData));
                    end
                    app.base(sel_row_project).project_name = string(event.NewData);
                catch ME
                    DisplayError(ME,'Writing project name to h5 failed')
                end
            end
        end

        % Menu selected function: SaveBaseasMenu
        function SaveBaseasMenuSelected(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the base file:');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(filename,0))

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Base...', ...
                    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');

                FileName = fullfile(filepath, filename);
                app.base(1).meta.basepath = FileName;
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;

                save(FileName, 'data2save', '-v7.3', '-nocompression');

                app.UIFigure.Name = app.base(1).meta.basepath;

                delete(progress)
            end
        end

        % Button pushed function: DuplicateProjectButton
        function DuplicateProjectButtonPushed(app, event)
            if isempty(app.ProjectTable.Selection)
                return
            end

            sel_project = app.ProjectTable.Selection(1);
            new_pid     = numel(app.base) + 1;

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Duplicating Project...', ...
                'Message', 'This may take a few seconds.', 'Indeterminate', 'on', 'Cancelable', 'off');

            try
                basepath = app.base(1).meta.basepath;
                [~,~,bext] = fileparts(basepath);
                if strcmp(bext, '.h5') && isfile(basepath)
                    src    = sprintf('/%d_project', sel_project);
                    dst    = sprintf('/%d_project', new_pid);
                    fid    = H5F.open(basepath, 'H5F_ACC_RDWR', 'H5P_DEFAULT');
                    ocpypl = H5P.create('H5P_OBJECT_COPY');
                    lcpl   = H5P.create('H5P_LINK_CREATE');
                    try
                        H5O.copy(fid, src, fid, dst, ocpypl, lcpl);
                    catch ME
                        H5P.close(ocpypl);
                        H5P.close(lcpl);
                        H5F.close(fid);
                        rethrow(ME);
                    end
                    H5P.close(ocpypl);
                    H5P.close(lcpl);
                    H5F.close(fid);
                end
                % possible to replace this with a connecth5
                app.base(new_pid) = app.base(sel_project);
            catch ME
                DisplayError(ME, 'Duplicating project in H5 failed')
            end

            delete(progress)
            update_project_table(app)
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            if isempty(app.ProjectTable.Selection)
                return
            end

            selection = app.ProjectTable.Selection(1);
            n = numel(app.base);

            pname = char(app.base(selection).project_name);
            answer = uiconfirm(app.UIFigure, ...
                sprintf(['Are you sure you want to delete project %d "%s"?\n\nThis will permanently remove the project and ALL its sessions from the base (i.e. the .h5 file stored on your computer).\n\nThis action CANNOT be undone and the data CANNOT be recovered.\n\n' ...
                'Make sure you have a backup if you need this data. You can export and import a single project using the "Project" Menu.'], selection, pname), ...
                'Delete Project', ...
                'Options', {'Delete permanently', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'error');

            if strcmp(answer, 'Cancel')
                return
            end

            try
                basepath = app.base(1).meta.basepath;
                [~,~,bext] = fileparts(basepath);
                if strcmp(bext, '.h5') && isfile(basepath)
                    fid = H5F.open(basepath, 'H5F_ACC_RDWR', 'H5P_DEFAULT');
                    try
                        H5L.delete(fid, sprintf('/%d_project', selection), 'H5P_DEFAULT');
                        for k = selection+1:n
                            H5L.move(fid, sprintf('/%d_project', k), fid, ...
                                sprintf('/%d_project', k-1), 'H5P_DEFAULT', 'H5P_DEFAULT');
                        end
                    catch ME
                        H5F.close(fid);
                        rethrow(ME);
                    end
                    H5F.close(fid);
                end
                app.base(selection) = [];
            catch ME
                DisplayError(ME, 'Deleting project from H5 failed')
            end

            update_project_table(app)
        end

        % Callback function: SessionTable
        function SessionTableSelectionChanged(app, event)
            % session_id is the selection

            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                session_id = app.SessionTable.Selection(1);
                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;
                update_progress_buttons(app, project_id, session_id)
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select export directory with read/write permission');
            delete(f)
            figure(app.UIFigure);

            if path==0
                app.WorkingDirectoryEditField.Value=pwd;
            else
                app.WorkingDirectoryEditField.Value = path;
                app.workingdirectory = path;
            end
            app.WorkingDirectoryEditField.Tooltip = app.WorkingDirectoryEditField.Value;

            WorkingDirectoryEditFieldValueChanged(app)


        end

        % Value changed function: WorkingDirectoryEditField
        function WorkingDirectoryEditFieldValueChanged(app, event)
            value = app.WorkingDirectoryEditField.Value;

            if not(exist(value,'dir'))

                uiconfirm(app.UIFigure,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir(pwd,'select working directory with read/write permission');
                delete(f)
                figure(app.UIFigure);

                if ~isequal(path,0)
                    cd(path)
                    app.WorkingDirectoryEditField.Value = path;
                else
                    app.WorkingDirectoryEditField.Value=pwd;
                end

                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            end
        end

        % Button pushed function: DeleteSessionButton
        function DeleteSessionButtonPushed(app, event)

            if isempty(app.SessionTable.Selection)
                return
            end

            selection = app.SessionTable.Selection(1);
            pid = app.ProjectTable.Selection(1);

            sname = char(app.base(pid).project(selection).session.session_name);
            answer = uiconfirm(app.UIFigure, ...
                sprintf(['Are you sure you want to delete session %d "%s"?\n\nThis will permanently remove the session from the base (i.e. the .h5 file stored on your computer).\n\nThis action CANNOT be undone and the data CANNOT be recovered.\n\n' ...
                'Make sure you have a backup if you need this data. You can export and import a single session using the "Session" Menu.'], selection, sname), ...
                'Delete Session', ...
                'Options', {'Delete permanently', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'error');

            if strcmp(answer, 'Cancel')
                return
            end


            n = numel(app.base(pid).project);

            try
                basepath = app.base(1).meta.basepath;
                [~,~,bext] = fileparts(basepath);
                if strcmp(bext, '.h5') && isfile(basepath)
                    fid = H5F.open(basepath, 'H5F_ACC_RDWR', 'H5P_DEFAULT');
                    try
                        H5L.delete(fid, sprintf('/%d_project/%d_session', pid, selection), 'H5P_DEFAULT');
                        for k = selection+1:n
                            H5L.move(fid, sprintf('/%d_project/%d_session', pid, k), fid, ...
                                sprintf('/%d_project/%d_session', pid, k-1), 'H5P_DEFAULT', 'H5P_DEFAULT');
                        end
                    catch ME
                        H5F.close(fid);
                        rethrow(ME);
                    end
                    H5F.close(fid);
                end
                app.base(pid).project(selection) = [];
            catch ME
                DisplayError(ME, 'Deleting session from H5 failed')
            end

            update_session_table(app, pid)
        end

        % Button pushed function: DuplicateSessionButton
        function DuplicateSessionButtonPushed(app, event)
            if isempty(app.SessionTable.Selection)
                return
            end

            pid         = app.ProjectTable.Selection(1);
            sel_session = app.SessionTable.Selection(1);
            new_sid     = numel(app.base(pid).project) + 1;

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Duplicating Session...', ...
                'Message', 'This may take a few seconds.', 'Indeterminate', 'on', 'Cancelable', 'off');


            % Duplicate in H5
            try
                basepath = app.base(1).meta.basepath;
                [~,~,bext] = fileparts(basepath);
                if strcmp(bext, '.h5') && isfile(basepath)
                    src = sprintf('/%d_project/%d_session', pid, sel_session);
                    dst = sprintf('/%d_project/%d_session', pid, new_sid);
                    fid    = H5F.open(basepath, 'H5F_ACC_RDWR', 'H5P_DEFAULT');
                    ocpypl = H5P.create('H5P_OBJECT_COPY');
                    lcpl   = H5P.create('H5P_LINK_CREATE');
                    try
                        H5O.copy(fid, src, fid, dst, ocpypl, lcpl);
                    catch ME
                        H5P.close(ocpypl);
                        H5P.close(lcpl);
                        H5F.close(fid);
                        rethrow(ME);
                    end
                    H5P.close(ocpypl);
                    H5P.close(lcpl);
                    H5F.close(fid);
                end
                % Duplicate in app.base skeleton
                app.base(pid).project(new_sid) = app.base(pid).project(sel_session);

            catch ME
                DisplayError(ME, 'Duplicating session in H5 failed')
            end

            delete(progress)
            update_session_table(app, pid)
        end

        % Button pushed function: TableBuilderVisModuleButton
        function TableBuilderVisModuleButtonPushed(app, event)

            Table_builder_Submodule(app)
        
        end

        % Menu selected function: ExportProjectMenu
        function ExportProjectMenuSelected2(app, event)
            if isempty(app.LastSelectedProjectRow)
                uialert(app.UIFigure, "No project selected", "Warning", "Icon", "warning");
                return
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.h5', 'Save the project file:');
            delete(f);
            figure(app.UIFigure);

            if isequal(filename, 0), return; end

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Exporting Project...', ...
                'Message', 'This may take a few seconds.', 'Cancelable', 'off', 'Indeterminate', 'on');

            try
                basepath = app.base(1).meta.basepath;
                dst_path = fullfile(filepath, filename);
                pid      = app.LastSelectedProjectRow;

                if exist(dst_path, 'file') == 2, delete(dst_path); end

                src_fid = H5F.open(basepath, 'H5F_ACC_RDONLY', 'H5P_DEFAULT');
                dst_fid = H5F.create(dst_path, 'H5F_ACC_TRUNC', 'H5P_DEFAULT', 'H5P_DEFAULT');
                ocpypl  = H5P.create('H5P_OBJECT_COPY');
                lcpl    = H5P.create('H5P_LINK_CREATE');
                try
                    H5O.copy(src_fid, sprintf('/%d_project', pid), dst_fid, '/1_project', ocpypl, lcpl);
                catch ME
                    H5P.close(ocpypl); H5P.close(lcpl);
                    H5F.close(src_fid); H5F.close(dst_fid);
                    rethrow(ME);
                end
                H5P.close(ocpypl); H5P.close(lcpl);
                H5F.close(src_fid); H5F.close(dst_fid);
            catch ME
                delete(progress)
                DisplayError(ME, 'Exporting project failed')
                return
            end

            delete(progress)
        end

        % Menu selected function: ImportProjectsMenu
        function ImportProjectsMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file, path] = uigetfile({'*.h5', 'H5 Base Files (*.h5)'}, "MultiSelect", "on", 'Import Project(s)');
            delete(f);
            figure(app.UIFigure);

            if isequal(file, 0), return; end
            if ischar(file), file = {file}; end

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Importing Project(s)...', ...
                'Message', 'This may take a few seconds.', 'Cancelable', 'off', 'Indeterminate', 'on');

            try
                basepath    = app.base(1).meta.basepath;
                base_count  = numel(app.base);
                dst_fid     = H5F.open(basepath, 'H5F_ACC_RDWR', 'H5P_DEFAULT');
                ocpypl      = H5P.create('H5P_OBJECT_COPY');
                lcpl        = H5P.create('H5P_LINK_CREATE');
                try
                    for i = 1:numel(file)
                        new_pid = base_count + i;
                        src_fid = H5F.open(fullfile(path, file{i}), 'H5F_ACC_RDONLY', 'H5P_DEFAULT');
                        try
                            H5O.copy(src_fid, '/1_project', dst_fid, sprintf('/%d_project', new_pid), ocpypl, lcpl);
                        catch ME
                            H5F.close(src_fid);
                            rethrow(ME);
                        end
                        H5F.close(src_fid);
                    end
                catch ME
                    H5P.close(ocpypl); H5P.close(lcpl);
                    H5F.close(dst_fid);
                    rethrow(ME);
                end
                H5P.close(ocpypl); H5P.close(lcpl);
                H5F.close(dst_fid);

                app.base = struct([]);
                connect_h5(app, basepath);
            catch ME
                delete(progress)
                DisplayError(ME, 'Importing project(s) failed')
                return
            end

            delete(progress)
            update_project_table(app)

        end

        % Menu selected function: ImportSessionsMenu
        function ImportSessionsMenuSelected(app, event)
            if isempty(app.LastSelectedProjectRow)
                uialert(app.UIFigure, "No project selected", "Warning", "Icon", "warning");
                return
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file, path] = uigetfile({'*.h5', 'H5 Session Files (*.h5)'}, "MultiSelect", "on", 'Import Session(s)');
            delete(f);
            figure(app.UIFigure);

            if isequal(file, 0), return; end
            if ischar(file), file = {file}; end

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Importing Session(s)...', ...
                'Message', 'This may take a few seconds.', 'Cancelable', 'off', 'Indeterminate', 'on');

            try
                basepath     = app.base(1).meta.basepath;
                pid          = app.LastSelectedProjectRow;
                session_count = numel(app.base(pid).project);
                if ~isfield(app.base(pid).project, 'session'), session_count = 0; end

                dst_fid = H5F.open(basepath, 'H5F_ACC_RDWR', 'H5P_DEFAULT');
                ocpypl  = H5P.create('H5P_OBJECT_COPY');
                lcpl    = H5P.create('H5P_LINK_CREATE');
                try
                    for i = 1:numel(file)
                        new_sid = session_count + i;
                        src_fid = H5F.open(fullfile(path, file{i}), 'H5F_ACC_RDONLY', 'H5P_DEFAULT');
                        try
                            H5O.copy(src_fid, '/1_session', dst_fid, ...
                                sprintf('/%d_project/%d_session', pid, new_sid), ocpypl, lcpl);
                        catch ME
                            H5F.close(src_fid);
                            rethrow(ME);
                        end
                        H5F.close(src_fid);
                    end
                catch ME
                    H5P.close(ocpypl); H5P.close(lcpl);
                    H5F.close(dst_fid);
                    rethrow(ME);
                end
                H5P.close(ocpypl); H5P.close(lcpl);
                H5F.close(dst_fid);

                app.base = struct([]);
                connect_h5(app, basepath);
            catch ME
                delete(progress)
                DisplayError(ME, 'Importing session(s) failed')
                return
            end

            delete(progress)
            update_session_table(app, pid)
        end

        % Menu selected function: ExportSessionMenu
        function ExportSessionMenuSelected(app, event)

            if isempty(app.LastSelectedProjectRow)
                uialert(app.UIFigure, "No project selected", "Warning", "Icon", "warning");
                return
            end
            if isempty(app.SessionTable.Selection)
                uialert(app.UIFigure, "No session selected", "Warning", "Icon", "warning");
                return
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.h5', 'Save the session file:');
            delete(f);
            figure(app.UIFigure);

            if isequal(filename, 0), return; end

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Exporting Session...', ...
                'Message', 'This may take a few seconds.', 'Cancelable', 'off', 'Indeterminate', 'on');

            try
                basepath = app.base(1).meta.basepath;
                pid      = app.LastSelectedProjectRow;
                sid      = app.SessionTable.Selection(1);
                dst_path = fullfile(filepath, filename);

                if exist(dst_path, 'file') == 2, delete(dst_path); end

                src_fid = H5F.open(basepath, 'H5F_ACC_RDONLY', 'H5P_DEFAULT');
                dst_fid = H5F.create(dst_path, 'H5F_ACC_TRUNC', 'H5P_DEFAULT', 'H5P_DEFAULT');
                ocpypl  = H5P.create('H5P_OBJECT_COPY');
                lcpl    = H5P.create('H5P_LINK_CREATE');
                try
                    H5O.copy(src_fid, sprintf('/%d_project/%d_session', pid, sid), ...
                        dst_fid, '/1_session', ocpypl, lcpl);
                catch ME
                    H5P.close(ocpypl); H5P.close(lcpl);
                    H5F.close(src_fid); H5F.close(dst_fid);
                    rethrow(ME);
                end
                H5P.close(ocpypl); H5P.close(lcpl);
                H5F.close(src_fid); H5F.close(dst_fid);
            catch ME
                delete(progress)
                DisplayError(ME, 'Exporting session failed')
                return
            end

            delete(progress)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Prompt user to save before closing
            selection = uiconfirm(app.UIFigure, ...
                'Did you save the base to your computer?', ...
                'Confirm Close', ...
                'Options', {'Save and Close', 'Close without Saving', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 3);

            switch selection
                case 'Save and Close'
                    
                    SaveBaseMenuSelected(app)
                    delete(app);
                case 'Close without Saving'
                    delete(app);
                case 'Cancel'
                    % Do nothing - keep app open
                    return;
            end

        end

        % Menu selected function: Saveh5Menu
        function Saveh5MenuSelected(app, event)
            
            progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Base...', ...
                'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');


            if isfield(app.base, 'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;
                try


                    save(app.base(1).meta.basepath, 'data2save', '-v7.3', '-nocompression'); %


                catch

                    message = "Saving the base failed. Did you change the file location of the base (.h5) file? Use the 'Save Base as' option.";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");

                end

                app.UIFigure.Name = app.base(1).meta.basepath;

            else
                SaveBaseasMenuSelected(app)
            end

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath')

                app.UIFigure.Name = app.base(1).meta.basepath;

            end

            delete(progress)


        end

        % Menu selected function: Saveash5Menu
        function Saveash5MenuSelected(app, event)
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.h5', 'Save the base file:');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(filename,0))

                %progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Base...', ...
                %    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');

                FileName = fullfile(filepath, filename);
                app.base(1).meta.basepath = FileName;
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;


                base2h5(app, app.base, FileName)


                app.UIFigure.Name = app.base(1).meta.basepath;

                %delete(progress)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ICP-Base';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.OpenBaseMenu = uimenu(app.BaseMenu);
            app.OpenBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBaseMenuSelected, true);
            app.OpenBaseMenu.Text = 'Open Base';
            app.SaveBaseMenu = uimenu(app.BaseMenu);
            app.SaveBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseMenuSelected, true);
            app.SaveBaseMenu.Text = 'Save Base';
            app.SaveBaseasMenu = uimenu(app.BaseMenu);
            app.SaveBaseasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseasMenuSelected, true);
            app.SaveBaseasMenu.Text = 'Save Base as';
            app.Saveh5Menu = uimenu(app.BaseMenu);
            app.Saveh5Menu.MenuSelectedFcn = createCallbackFcn(app, @Saveh5MenuSelected, true);
            app.Saveh5Menu.Text = 'Save h5';
            app.Saveash5Menu = uimenu(app.BaseMenu);
            app.Saveash5Menu.MenuSelectedFcn = createCallbackFcn(app, @Saveash5MenuSelected, true);
            app.Saveash5Menu.Text = 'Save as h5';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.ImportProjectsMenu = uimenu(app.ProjectMenu);
            app.ImportProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportProjectsMenuSelected, true);
            app.ImportProjectsMenu.Text = 'Import Project(s)';
            app.ExportProjectMenu = uimenu(app.ProjectMenu);
            app.ExportProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportProjectMenuSelected2, true);
            app.ExportProjectMenu.Text = 'Export Project';
            app.SessionMenu = uimenu(app.UIFigure);
            app.SessionMenu.Text = 'Session';
            app.ImportSessionsMenu = uimenu(app.SessionMenu);
            app.ImportSessionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSessionsMenuSelected, true);
            app.ImportSessionsMenu.Text = 'Import Session(s)';
            app.ExportSessionMenu = uimenu(app.SessionMenu);
            app.ExportSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSessionMenuSelected, true);
            app.ExportSessionMenu.Text = 'Export Session';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Show Session in Import Module';
            app.AddSessionButton = uibutton(app.GridLayout, 'push');
            app.AddSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSessionButtonPushed, true);
            app.AddSessionButton.Text = 'Add Session';
            app.SessionTable = uitable(app.GridLayout);
            app.SessionTable.ColumnName = '';
            app.SessionTable.RowName = {};
            app.SessionTable.ColumnEditable = [false true true true true true];
            app.SessionTable.CellEditCallback = createCallbackFcn(app, @SessionTableCellEdit, true);
            app.SessionTable.SelectionChangedFcn = createCallbackFcn(app, @SessionTableSelectionChanged, true);
            app.SessionTable.Multiselect = 'off';
            app.DeleteSessionButton = uibutton(app.GridLayout, 'push');
            app.DeleteSessionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSessionButtonPushed, true);
            app.DeleteSessionButton.Text = 'Delete Session';
            app.IntervalButton = uibutton(app.GridLayout, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Show Session in Interval Module';
            app.ProjectTable = uitable(app.GridLayout);
            app.ProjectTable.ColumnName = '';
            app.ProjectTable.RowName = {};
            app.ProjectTable.ColumnEditable = [false true];
            app.ProjectTable.CellEditCallback = createCallbackFcn(app, @ProjectTableCellEdit, true);
            app.ProjectTable.SelectionChangedFcn = createCallbackFcn(app, @ProjectTableSelectionChanged, true);
            app.ProjectTable.Multiselect = 'off';
            app.AddProjectButton = uibutton(app.GridLayout, 'push');
            app.AddProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddProjectButtonPushed, true);
            app.AddProjectButton.Text = 'Add Project';
            app.DeleteProjectButton = uibutton(app.GridLayout, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Text = 'Delete Project';
            app.ClassificationButton = uibutton(app.GridLayout, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Show Session in Classification Module';
            app.DriftButton = uibutton(app.GridLayout, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.DriftButton.Text = 'Show Session in Drift Module';
            app.QuantificationButton = uibutton(app.GridLayout, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Show Session in Quantification Module';
            app.ResultsButton = uibutton(app.GridLayout, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Show Session in Results Module';
            app.WorkingDirectoryLabel = uilabel(app.GridLayout);
            app.WorkingDirectoryLabel.Text = 'Working Directory:';
            app.WorkingDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.WorkingDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryEditFieldValueChanged, true);
            app.WorkingDirectoryEditField.Editable = 'off';
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DuplicateSessionButton = uibutton(app.GridLayout, 'push');
            app.DuplicateSessionButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateSessionButtonPushed, true);
            app.DuplicateSessionButton.Text = 'Duplicate Session';
            app.DuplicateProjectButton = uibutton(app.GridLayout, 'push');
            app.DuplicateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateProjectButtonPushed, true);
            app.DuplicateProjectButton.Text = 'Duplicate Project';
            app.TableBuilderVisModuleButton = uibutton(app.GridLayout, 'push');
            app.TableBuilderVisModuleButton.ButtonPushedFcn = createCallbackFcn(app, @TableBuilderVisModuleButtonPushed, true);
            app.TableBuilderVisModuleButton.Text = 'Table Builder & VisModule';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICP_Base

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end